classdef RunCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RunButton             matlab.ui.control.Button
        ExitSimulationButton  matlab.ui.control.Button
        ResumeButton          matlab.ui.control.Button
        StopEmergencyButton   matlab.ui.control.Button
        ESTOPButton           matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function plotFigureFromFile(filePath, uiaxes)
            f = openfig(filePath);
            axesObj = f.CurrentAxes;
            copyobj(axesObj.Children,uiaxes);
            close (f);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Main;
            % fig = gcf;
            % axesHandle = app.UIFigure;
            % copyobj(get(fig,'Childrend'), axesHandle);

            run("Main.m");
            f = gcf;
            copyobj(f.Children, app.UIAxes);

            % filePath = fullfile(pwd, 'Main.m');
            % plotFigureFromFile(filePath,app.UIAxes);
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            %updates two variables, 
            stop = 1; %stops motion
            run = 0; %necessary to resume
        end

        % Button pushed function: StopEmergencyButton
        function StopEmergencyButtonPushed(app, event)
            stop = 0; %stops emergency, but resume is nececssary to continue motion
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            run = 1; %resumes motion
        end

        % Button pushed function: ExitSimulationButton
        function ExitSimulationButtonPushed(app, event)
            GUI;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.StopEmergencyButton = uibutton(app.UIFigure, 'push');
            app.StopEmergencyButton.ButtonPushedFcn = createCallbackFcn(app, @StopEmergencyButtonPushed, true);
            app.StopEmergencyButton.Text = 'Stop Emergency';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.ExitSimulationButton = uibutton(app.UIFigure, 'push');
            app.ExitSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ExitSimulationButtonPushed, true);
            app.ExitSimulationButton.Text = 'Exit Simulation';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end