classdef SimulateRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ExitSimulationButton  matlab.ui.control.Button
        LoadRobotButton       matlab.ui.control.Button
        Q5Slider              matlab.ui.control.Slider
        Q5SliderLabel         matlab.ui.control.Label
        Q4Slider              matlab.ui.control.Slider
        Q4SliderLabel         matlab.ui.control.Label
        Spinner_6             matlab.ui.control.Spinner
        Spinner_5             matlab.ui.control.Spinner
        Spinner_4             matlab.ui.control.Spinner
        Spinner_3             matlab.ui.control.Spinner
        Spinner_2             matlab.ui.control.Spinner
        Spinner               matlab.ui.control.Spinner
        Q3Slider              matlab.ui.control.Slider
        Q3SliderLabel         matlab.ui.control.Label
        Q2Slider              matlab.ui.control.Slider
        Q2SliderLabel         matlab.ui.control.Label
        Q1Slider              matlab.ui.control.Slider
        Q1SliderLabel         matlab.ui.control.Label
        Q0Slider              matlab.ui.control.Slider
        Q0SliderLabel         matlab.ui.control.Label
        BackButton            matlab.ui.control.Button
        LeftButton            matlab.ui.control.Button
        RightButton           matlab.ui.control.Button
        ForwardButton         matlab.ui.control.Button
        DownButton            matlab.ui.control.Button
        UpButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
         % Description
         Drawbot % where I will put the drawbot
         qrDrawbot = [0 0 0]
    end
    
    methods (Access = private)
        
        function init(app,varargin)
           %code to run once gui is initialised 
           ax = gca;

           %make robots
           drawbot = Drawbot();
           app.Drawbot = drawbot;
           app.Drawbot.base= transl([0,0,0]);
           plot(app.Drawbot,'workspace',[-2 2 -2 2 -0.5 2]);
           hold on;
           drawnow();

            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            %enable move button 
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Q0Slider
        function Q0SliderValueChanged(app, event)
            Value = app.Q0Slider.Value;
            app.Spinner.Value = app.Q0Slider.Value;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            Value = app.Spinner.Value;
            if Value < app.Q0Slider.Limits(1) || Value > app.Q0Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner.Value = 0;
            else 
                app.Q0Slider.Value = app.Spinner.Value;
            end
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            Value = app.Q1Slider.Value;
            app.Spinner_2.Value = app.Q1Slider.Value;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            Value = app.Spinner_2.Value;
            if Value < app.Q1Slider.Limits(1) || Value > app.Q1Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner_2.Value = event.PreviousValue;
            else 
                app.Q1Slider.Value = app.Spinner_2.Value;
            end
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
            Value = app.Q2Slider.Value;
            app.Spinner_3.Value = app.Q2Slider.Value;

        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            Value = app.Spinner_3.Value;
            if Value < app.Q2Slider.Limits(1) || Value > app.Q2Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner_3.Value = event.PreviousValue;
            else 
                app.Q2Slider.Value = app.Spinner_3.Value;
            end

        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
            Value = app.Q3Slider.Value;
            app.Spinner_4.Value = app.Q3Slider.Value;

        end

        % Value changed function: Spinner_4
        function Spinner_4ValueChanged(app, event)
            Value = app.Spinner_4.Value;
            if Value < app.Q3Slider.Limits(1) || Value > app.Q3Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner_4.Value = event.PreviousValue;
            else 
                app.Q3Slider.Value = app.Spinner_4.Value;
            end

        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
            Value = app.Q4Slider.Value;
            app.Spinner_5.Value = app.Q4Slider.Value;

        end

        % Value changed function: Spinner_5
        function Spinner_5ValueChanged(app, event)
            Value = app.Spinner_5.Value;
            if Value < app.Q4Slider.Limits(1) || Value > app.Q4Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner_5.Value = event.PreviousValue;
            else 
                app.Q4Slider.Value = app.Spinner_5.Value;
            end

        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
            Value = app.Q5Slider.Value;
            app.Spinner_6.Value = app.Q5Slider.Value;

        end

        % Value changed function: Spinner_6
        function Spinner_6ValueChanged(app, event)
            Value = app.Spinner_6.Value;
            if Value < app.Q5Slider.Limits(1) || Value > app.Q5Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner_6.Value = event.PreviousValue;
            else 
                app.Q5Slider.Value = app.Spinner_6.Value;
            end

        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Q0SliderLabel = uilabel(app.UIFigure);
            app.Q0SliderLabel.Text = 'Q0';
            app.Q0Slider = uislider(app.UIFigure);
            app.Q0Slider.Limits = [-360 360];
            app.Q0Slider.ValueChangedFcn = createCallbackFcn(app, @Q0SliderValueChanged, true);
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-90 90];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-170 170];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-360 360];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_4ValueChanged, true);
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_5ValueChanged, true);
            app.Spinner_6 = uispinner(app.UIFigure);
            app.Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_6ValueChanged, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-360 360];
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-360 360];
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.LoadRobotButton = uibutton(app.UIFigure, 'push');
            app.LoadRobotButton.Text = 'Load Robot';
            app.ExitSimulationButton = uibutton(app.UIFigure, 'push');
            app.ExitSimulationButton.Text = 'Exit Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulateRobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end