classdef testRobotPlotApp_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        ExitsimulationButton  matlab.ui.control.Button
        BackButton            matlab.ui.control.Button
        ForwardButton         matlab.ui.control.Button
        YaxisLabel            matlab.ui.control.Label
        RightButton           matlab.ui.control.Button
        LeftButton            matlab.ui.control.Button
        XaxisLabel            matlab.ui.control.Label
        Spinner2              matlab.ui.control.Spinner
        Spinner               matlab.ui.control.Spinner
        Q1Slider              matlab.ui.control.Slider
        Q1SliderLabel         matlab.ui.control.Label
        Q0Slider              matlab.ui.control.Slider
        Q0SliderLabel         matlab.ui.control.Label
        plot                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        Drawbot
        qrDrawbot = [pi/2 pi/2 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;          

            %load drawbot
            app.Drawbot = Drawbot;
            app.Drawbot.model.base = transl([0,0,0]);
            app.Drawbot.model.animate(app.qrDrawbot);
            hold on;
            drawnow();


            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % enable load buttons
            
        end

        % Value changed function: Q0Slider
        function Q0SliderValueChanged(app, event)
            Value = app.Q0Slider.Value;
            app.Spinner.Value = app.Q0Slider.Value;

            %find current joint values 
            endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
            M = [1 1 1 0 0 0];
            qMatrix = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);

            jointVal = Value*pi/180;

            jointMatrix = [jointVal, qMatrix(2), qMatrix(3)];

            app.Drawbot.model.animate(jointMatrix); 
            drawnow();
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            Value = app.Spinner.Value;
            if Value < app.Q0Slider.Limits(1) || Value > app.Q0Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner.Value = 0;
            else 
                app.Q0Slider.Value = app.Spinner.Value;
                %find current joint values 
                endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
                M = [1 1 1 0 0 0];
                qMatrix = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);
    
                jointVal = Value*pi/180;
    
                jointMatrix = [jointVal, qMatrix(2), qMatrix(3)];
    
                app.Drawbot.model.animate(jointMatrix); 
                drawnow();
            end
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            Value = app.Q1Slider.Value;
            app.Spinner2.Value = app.Q1Slider.Value;

            %find current joint values 
            endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
            M = [1 1 1 0 0 0];
            qMatrix = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);

            jointVal = Value*pi/180;

            jointMatrix = [qMatrix(1), jointVal, qMatrix(3)];

            app.Drawbot.model.animate(jointMatrix); 
            drawnow();

        end

        % Value changed function: Spinner2
        function Spinner2ValueChanged(app, event)
            Value = app.Spinner2.Value;
            if Value < app.Q1Slider.Limits(1) || Value > app.Q1Slider.Limits(2)
                %if out of bounds, display error message
                errordlg('Value out of range');
                app.Spinner2.Value = event.PreviousValue;
            else 
                app.Q1Slider.Value = app.Spinner2.Value;
                endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
                M = [1 1 1 0 0 0];
                qMatrix = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);
    
                jointVal = Value*pi/180;
    
                jointMatrix = [qMatrix(1), jointVal, qMatrix(3)];
    
                app.Drawbot.model.animate(jointMatrix); 
                drawnow();
            end

        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            %initial conditions
            steps = 50;
            M = [1 1 1 0 0 0];
            deltaT = 0.05;

            %find position of end effector
            endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
            x1 = endEffectorPos(1:3,4);

            %calculate point 0.1 to the left of position

            x2 = [x1(1) - 0.1, x1(2), x1(3)]';
            x = zeros(3,steps);
            s = lspb(0,1,steps);                                 % Create interpolation scalar
            for i = 1:steps
                x(:,i) = x1*(1-s(i)) + s(i)*x2;               % Create trajectory in x-y plane
            end

            %complete RMRC

            qMatrix = nan(steps,3);
            qMatrix(1,:) = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);

            for i = 1:steps-1
                xdot = (x(:,i+1) - x(:,i))/deltaT;                            % Calculate velocity at discrete time step
                J = app.Drawbot.model.jacob0(qMatrix(i,:));          % Get the Jacobian at the current state
                J = J(1:3,:);                           % Take only first 2 rows
                qdot = pinv(J)*xdot;                             % Solve velocitities via RMRC
                qMatrix(i+1,:) =  qMatrix(i,:) + deltaT*qdot';                   % Update next joint state
                app.Drawbot.model.animate(qMatrix(i+1,:));
                drawnow();
            end

        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            %initial conditions
            steps = 50;
            M = [1 1 1 0 0 0];
            deltaT = 0.05;

            %find position of end effector
            endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
            x1 = endEffectorPos(1:3,4);

            %calculate point 0.1 to the left of position

            x2 = [x1(1) + 0.1, x1(2), x1(3)]';
            x = zeros(3,steps);
            s = lspb(0,1,steps);                                 % Create interpolation scalar
            for i = 1:steps
                x(:,i) = x1*(1-s(i)) + s(i)*x2;               % Create trajectory in x-y plane
            end

            %complete RMRC

            qMatrix = nan(steps,3);
            qMatrix(1,:) = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);

            for i = 1:steps-1
                xdot = (x(:,i+1) - x(:,i))/deltaT;                            % Calculate velocity at discrete time step
                J = app.Drawbot.model.jacob0(qMatrix(i,:));          % Get the Jacobian at the current state
                J = J(1:3,:);                           % Take only first 2 rows
                qdot = pinv(J)*xdot;                             % Solve velocitities via RMRC
                qMatrix(i+1,:) =  qMatrix(i,:) + deltaT*qdot';                   % Update next joint state
                app.Drawbot.model.animate(qMatrix(i+1,:));
                drawnow();
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %initial conditions
            steps = 50;
            M = [1 1 1 0 0 0];
            deltaT = 0.05;

            %find position of end effector
            endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
            x1 = endEffectorPos(1:3,4);

            %calculate point 0.1 to the left of position

            x2 = [x1(1), x1(2) + 0.1, x1(3)]';
            x = zeros(3,steps);
            s = lspb(0,1,steps);                                 % Create interpolation scalar
            for i = 1:steps
                x(:,i) = x1*(1-s(i)) + s(i)*x2;               % Create trajectory in x-y plane
            end

            %complete RMRC

            qMatrix = nan(steps,3);
            qMatrix(1,:) = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);

            for i = 1:steps-1
                xdot = (x(:,i+1) - x(:,i))/deltaT;                            % Calculate velocity at discrete time step
                J = app.Drawbot.model.jacob0(qMatrix(i,:));          % Get the Jacobian at the current state
                J = J(1:3,:);                           % Take only first 2 rows
                qdot = pinv(J)*xdot;                             % Solve velocitities via RMRC
                qMatrix(i+1,:) =  qMatrix(i,:) + deltaT*qdot';                   % Update next joint state
                app.Drawbot.model.animate(qMatrix(i+1,:));
                drawnow();
            end   
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            %initial conditions
            steps = 50;
            M = [1 1 1 0 0 0];
            deltaT = 0.05;

            %find position of end effector
            endEffectorPos = app.Drawbot.model.fkine(app.Drawbot.model.getpos).T;
            x1 = endEffectorPos(1:3,4);

            %calculate point 0.1 to the left of position

            x2 = [x1(1), x1(2) - 0.1, x1(3)]';
            x = zeros(3,steps);
            s = lspb(0,1,steps);                                 % Create interpolation scalar
            for i = 1:steps
                x(:,i) = x1*(1-s(i)) + s(i)*x2;               % Create trajectory in x-y plane
            end

            %complete RMRC

            qMatrix = nan(steps,3);
            qMatrix(1,:) = app.Drawbot.model.ikine(endEffectorPos,'q0',[0,0,0],'mask',M);

            for i = 1:steps-1
                xdot = (x(:,i+1) - x(:,i))/deltaT;                            % Calculate velocity at discrete time step
                J = app.Drawbot.model.jacob0(qMatrix(i,:));          % Get the Jacobian at the current state
                J = J(1:3,:);                           % Take only first 2 rows
                qdot = pinv(J)*xdot;                             % Solve velocitities via RMRC
                qMatrix(i+1,:) =  qMatrix(i,:) + deltaT*qdot';                   % Update next joint state
                app.Drawbot.model.animate(qMatrix(i+1,:));
                drawnow();
            end 
        end

        % Button pushed function: ExitsimulationButton
        function ExitsimulationButtonPushed(app, event)
            GUI;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.Q0SliderLabel = uilabel(app.figure1);
            app.Q0SliderLabel.Text = 'Q0';
            app.Q0Slider = uislider(app.figure1);
            app.Q0Slider.Limits = [-90 90];
            app.Q0Slider.ValueChangedFcn = createCallbackFcn(app, @Q0SliderValueChanged, true);
            app.Q1SliderLabel = uilabel(app.figure1);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.figure1);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Spinner = uispinner(app.figure1);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner2 = uispinner(app.figure1);
            app.Spinner2.ValueChangedFcn = createCallbackFcn(app, @Spinner2ValueChanged, true);
            app.XaxisLabel = uilabel(app.figure1);
            app.XaxisLabel.Text = 'X-axis';
            app.LeftButton = uibutton(app.figure1, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.figure1, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.YaxisLabel = uilabel(app.figure1);
            app.YaxisLabel.Text = 'Y-axis';
            app.ForwardButton = uibutton(app.figure1, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackButton = uibutton(app.figure1, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitsimulationButton = uibutton(app.figure1, 'push');
            app.ExitsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ExitsimulationButtonPushed, true);
            app.ExitsimulationButton.Text = 'Exit simulation';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testRobotPlotApp_V2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end